classdef speech_recognition_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RecognizeButton               matlab.ui.control.Button
        LoadTemplateButton            matlab.ui.control.Button
        RecognizedWordEditField       matlab.ui.control.EditField
        RecognizedWordEditFieldLabel  matlab.ui.control.Label
        AudioInput                    matlab.ui.control.EditField
        AudioInputEditFieldLabel      matlab.ui.control.Label
        SelectAudioButton             matlab.ui.control.Button
        LogWindow                     matlab.ui.control.TextArea
        ThresholdSlider               matlab.ui.control.Slider
        ThresholdSliderLabel          matlab.ui.control.Label
        CurrentTemplateFolder         matlab.ui.control.EditField
        CurrentFolderLabel            matlab.ui.control.Label
        SelectFolderButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
    TemplateFilePath % Stores the file path selected in selectTemplateButtonPushed
    TemplateFileName
    InputAudioFileName
    InputAudioFilePath
    Data
    Templates
    TemplateDirectory
    end
    
    methods (Access = public)
        function appendTextToTextArea(app, textArea, newText)
            % Appends newText to the specified text area, or replaces the value if empty
            % Parameters:
            %   textArea: The text area component (e.g., app.TextArea)
            %   newText: The text to append (string or cell array of strings)
    
            existingText = textArea.Value; % Get the current text
    
            % Handle empty text area
            if isempty(existingText)
                if ischar(newText)
                    textArea.Value = {newText}; % Replace with new text
                else
                    textArea.Value = newText; % Assign directly if newText is already a cell array
                end
            else
                % Append to existing text
                if ischar(existingText)
                    existingText = {existingText}; % Convert to cell array
                end
                if ischar(newText)
                    newText = {newText}; % Ensure new text is in cell array format
                end
                textArea.Value = [existingText; newText]; % Append new text
            end
        end
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            folderPath = uigetdir("./", "Select Templates Folder");
            delete(f);
            app.TemplateDirectory = folderPath;
            % Extract the last folder name from the full path
            [~, folderName, ~] = fileparts(folderPath);

           app.CurrentTemplateFolder.Value = folderName;
        end

        % Button pushed function: LoadTemplateButton
        function LoadTemplateButtonPushed(app, event)
            app.appendTextToTextArea(app.LogWindow,'Preloading templates...');

            templateDirectory = app.TemplateDirectory;
            fileList = dir(fullfile(templateDirectory, '**', '*.wav'));
            templateFiles = fullfile({fileList.folder}, {fileList.name});
            if exist('templates.mat', 'file')
                load('templates.mat');
                app.appendTextToTextArea(app.LogWindow,'Templates has been loaded successfully.');
            else
                % Preload and normalize templates as before
                templates = cell(1, length(templateFiles));
                templateFsList = zeros(1, length(templateFiles));
                 for i = 1:length(templateFiles)
                     [templateSignal, templateFs] = audioread(templateFiles{i});
                     templates{i} = templateSignal / max(abs(templateSignal)); % Normalize template
                     templateFsList(i) = templateFs;
                
                     % Inform user about progress
                     if mod(i, 1000) == 0 || i == length(templateFiles)
                         formattedText = sprintf('Loaded %d of %d templates...\n', i, length(templateFiles));
                         app.appendTextToTextArea(app.LogWindow,formattedText);
                     end
                 end
                app.appendTextToTextArea(app.LogWindow,'Saving templates...');
                save('templates.mat', 'templates', 'templateFsList');
                app.appendTextToTextArea(app.LogWindow,'Template Saved Successfully!');
            end
        end

        % Button pushed function: SelectAudioButton
        function SelectAudioButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [fileName, filePath] = uigetfile('*.wav', 'Select Input Audio', 'yes.wav');
            delete(f);
            if isequal(fileName,0)
               return
            else
               app.InputAudioFilePath = filePath;
               app.InputAudioFileName = fileName;
               app.AudioInput.Value = fileName;
            end
        end

        % Button pushed function: RecognizeButton
        function RecognizeButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.CurrentFolderLabel = uilabel(app.UIFigure);
            app.CurrentFolderLabel.Text = 'Current Template Folder:';
            app.CurrentTemplateFolder = uieditfield(app.UIFigure, 'text');
            app.CurrentTemplateFolder.Tag = 'templateName';
            app.CurrentTemplateFolder.Editable = 'off';
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.ThresholdSlider.Tag = 'threshold';
            app.LogWindow = uitextarea(app.UIFigure);
            app.LogWindow.Editable = 'off';
            app.SelectAudioButton = uibutton(app.UIFigure, 'push');
            app.SelectAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAudioButtonPushed, true);
            app.SelectAudioButton.Text = 'Select Audio';
            app.AudioInputEditFieldLabel = uilabel(app.UIFigure);
            app.AudioInputEditFieldLabel.Text = 'Audio Input:';
            app.AudioInput = uieditfield(app.UIFigure, 'text');
            app.AudioInput.Editable = 'off';
            app.RecognizedWordEditFieldLabel = uilabel(app.UIFigure);
            app.RecognizedWordEditFieldLabel.Text = 'Recognized Word:';
            app.RecognizedWordEditField = uieditfield(app.UIFigure, 'text');
            app.LoadTemplateButton = uibutton(app.UIFigure, 'push');
            app.LoadTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTemplateButtonPushed, true);
            app.LoadTemplateButton.Text = 'Load Template';
            app.RecognizeButton = uibutton(app.UIFigure, 'push');
            app.RecognizeButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizeButtonPushed, true);
            app.RecognizeButton.Text = 'Recognize Audio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = speech_recognition_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end